classdef updated_app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        row_5_column_1  matlab.ui.control.Label
        row_4_column_1  matlab.ui.control.Label
        row_3_column_1  matlab.ui.control.Label
        row_2_column_1  matlab.ui.control.Label
        row_1_column_1  matlab.ui.control.Label
        edit_field      matlab.ui.control.EditField
        submitButton    matlab.ui.control.Button
        row_5_column_4  matlab.ui.control.Label
        row_4_column_4  matlab.ui.control.Label
        row_3_column_4  matlab.ui.control.Label
        row_2_column_4  matlab.ui.control.Label
        row_1_column_4  matlab.ui.control.Label
        row_5_column_3  matlab.ui.control.Label
        row_4_column_3  matlab.ui.control.Label
        row_3_column_3  matlab.ui.control.Label
        row_2_column_3  matlab.ui.control.Label
        row_1_column_3  matlab.ui.control.Label
        row_5_column_2  matlab.ui.control.Label
        row_4_column_2  matlab.ui.control.Label
        row_3_column_2  matlab.ui.control.Label
        row_2_column_2  matlab.ui.control.Label
        row_1_column_2  matlab.ui.control.Label
        guess_the_word  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: submitButton
        function submitButtonPushed(comp, event)
         
comp.row_1_column_1.Text = comp.edit_field.Value(1,1);
comp.row_1_column_2.Text = comp.edit_field.Value(1,2);
comp.row_1_column_3.Text = comp.edit_field.Value(1,3);
comp.row_1_column_4.Text = comp.edit_field.Value(1,4);


% current_color = get(comp.row_1_column_1, 'BackgroundColor');
color_green = [0 1 0];
color_red = [1 0 0];
color_yellow = [1 1 0];

if comp.row_1_column_1.Text == 'e'
    set(comp.row_1_column_1, 'BackgroundColor', color_green);
elseif comp.row_1_column_1.Text == 'd'
    set(comp.row_1_column_1, 'BackgroundColor', color_yellow);
elseif comp.row_1_column_1.Text == 'n'
    set(comp.row_1_column_1, 'BackgroundColor', color_yellow);
else
    set(comp.row_1_column_1, 'BackgroundColor', color_red);
end

if comp.row_1_column_2.Text == 'd'
    set(comp.row_1_column_2, 'BackgroundColor', color_green);
else
    set(comp.row_1_column_2, 'BackgroundColor', color_red);
end

if comp.row_1_column_3.Text == 'e'
    set(comp.row_1_column_3, 'BackgroundColor', color_green);
else
    set(comp.row_1_column_3, 'BackgroundColor', color_red);
end

if comp.row_1_column_4.Text == 'n'
    set(comp.row_1_column_4, 'BackgroundColor', color_green);
else
    set(comp.row_1_column_4, 'BackgroundColor', color_red);
end

% disp(comp.row_1_column_1.BackgroundColor)
            % read from text area, make vector 
            %%% make sure in right row
            %%% for loop
            %%%need variable which keeps tract of row 
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 452 472];
            comp.GridLayout = uigridlayout(comp);
            comp.guess_the_word = uilabel(comp.GridLayout);
            comp.guess_the_word.Text = 'Guess the word and click submit to see if it''s correct!';
            comp.row_1_column_2 = uilabel(comp.GridLayout);
            comp.row_1_column_2.Text = '';
            comp.row_2_column_2 = uilabel(comp.GridLayout);
            comp.row_2_column_2.Text = '';
            comp.row_3_column_2 = uilabel(comp.GridLayout);
            comp.row_3_column_2.Text = '';
            comp.row_4_column_2 = uilabel(comp.GridLayout);
            comp.row_4_column_2.Text = '';
            comp.row_5_column_2 = uilabel(comp.GridLayout);
            comp.row_5_column_2.Text = '';
            comp.row_1_column_3 = uilabel(comp.GridLayout);
            comp.row_1_column_3.Text = '';
            comp.row_2_column_3 = uilabel(comp.GridLayout);
            comp.row_2_column_3.Text = '';
            comp.row_3_column_3 = uilabel(comp.GridLayout);
            comp.row_3_column_3.Text = '';
            comp.row_4_column_3 = uilabel(comp.GridLayout);
            comp.row_4_column_3.Text = '';
            comp.row_5_column_3 = uilabel(comp.GridLayout);
            comp.row_5_column_3.Text = '';
            comp.row_1_column_4 = uilabel(comp.GridLayout);
            comp.row_1_column_4.Text = '';
            comp.row_2_column_4 = uilabel(comp.GridLayout);
            comp.row_2_column_4.Text = '';
            comp.row_3_column_4 = uilabel(comp.GridLayout);
            comp.row_3_column_4.Text = '';
            comp.row_4_column_4 = uilabel(comp.GridLayout);
            comp.row_4_column_4.Text = '';
            comp.row_5_column_4 = uilabel(comp.GridLayout);
            comp.row_5_column_4.Text = '';
            comp.submitButton = uibutton(comp.GridLayout, 'push');
            comp.submitButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @submitButtonPushed, true);
            comp.submitButton.Text = 'submit';
            comp.edit_field = uieditfield(comp.GridLayout, 'text');
            comp.edit_field.CharacterLimits = [0 4];
            comp.edit_field.InputType = 'letters';
            comp.row_1_column_1 = uilabel(comp.GridLayout);
            comp.row_1_column_1.Text = '';
            comp.row_2_column_1 = uilabel(comp.GridLayout);
            comp.row_2_column_1.Text = '';
            comp.row_3_column_1 = uilabel(comp.GridLayout);
            comp.row_3_column_1.Text = '';
            comp.row_4_column_1 = uilabel(comp.GridLayout);
            comp.row_4_column_1.Text = '';
            comp.row_5_column_1 = uilabel(comp.GridLayout);
            comp.row_5_column_1.Text = '';
        end
    end
end